classdef MOT_CC_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadTrackletsButton         matlab.ui.control.Button
        PathTextAreaLabel           matlab.ui.control.Label
        PathTextArea                matlab.ui.control.TextArea
        MethodsButtonGroup          matlab.ui.container.ButtonGroup
        GantPlotCheckBox            matlab.ui.control.CheckBox
        RiskPlotCheckBox            matlab.ui.control.CheckBox
        FrameRangePanel             matlab.ui.container.Panel
        LeftLabel                   matlab.ui.control.Label
        LeftEditField               matlab.ui.control.EditField
        RightEditFieldLabel         matlab.ui.control.Label
        RightEditField              matlab.ui.control.EditField
        ChooseRangesButton          matlab.ui.control.Button
        VideoPlotButton             matlab.ui.control.Button
        ButtonGroup                 matlab.ui.container.ButtonGroup
        LoadButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        WriteVideoButton            matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        BreakTrackletTab            matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        BreakUpdateButton           matlab.ui.control.Button
        AddTrackBreakButton         matlab.ui.control.Button
        ConnectTrackletsTab         matlab.ui.container.Tab
        UITable2                    matlab.ui.control.Table
        AddTrackConnectButton       matlab.ui.control.Button
        ConnectUpdateButton         matlab.ui.control.Button
        ManualTrackletsTab          matlab.ui.container.Tab
        DeleteFullycontainedButton  matlab.ui.control.Button
        AddManualTrackingButton     matlab.ui.control.Button
        ObjNumEditField             matlab.ui.control.EditField
        Panel                       matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        StatusTextAreaLabel         matlab.ui.control.Label
        StatusTextArea              matlab.ui.control.TextArea
        ObjNumButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        TrackPath     % path of .mat data of tracklets
        TrackFile     % file of .mat data of tracklets
        ObjNumber     % number of object
        TrackOrigi    % original tracklets
        TrackModi     % modified tracklets
        VideoObj      % video object
        ChooseRange          % selected frame range to modify
        ChooseRangeTrack     % tracklets of choose range
        RiskControlled   % controlled frames 0/1
        
    end
    
    methods (Access = private)
        
        
        function UpdateTracklets(app)
            TR_Track = app.ChooseRangeTrack;
            FrameInd=app.ChooseRange;
            TR_Track_Pre= TrackletsCut(app.TrackModi,[1 FrameInd(1)]);
            TR_Dura=[app.TrackModi(:).StartEnd];
            TR_Track_Post=TrackletsCut(app.TrackModi,[FrameInd(end) max(TR_Dura(2,:))]);
            app.TrackModi=TrackletsMergeOperationCross_V1(TR_Track_Pre,TR_Track,TR_Track_Post);
            
            % VideoPlotButtonPushed(app, event)
            if app.GantPlotCheckBox.Value
                GantPlotCheckBoxValueChanged(app, event)
            end
            if app.RiskPlotCheckBox.Value
                RiskPlotCheckBoxValueChanged(app, event)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialization
            app.TrackPath=[];    % path of .mat data of tracklets
            app.TrackFile=[];    % file of .mat data of tracklets
            app.ObjNumber=[];    % number of object
            app.TrackOrigi=[];   % original tracklets
            app.TrackModi=[];    % modified tracklets
            app.VideoObj=[];     % video object | name, for transibility
            app.ChooseRange=[];            % selected frame range to modify
            app.ChooseRangeTrack=[];   % tracklets of choose range
            app.RiskControlled=[];     % controlled frames 0/1
            
            app.UIAxes.Visible = 'off';
        end

        % Button pushed function: LoadTrackletsButton
        function LoadTrackletsButtonPushed(app, event)
            % load .mat data of tracklets
            [TrackFile1,TrackPath1] = uigetfile('','Load Tracklets...');
            try
                load(fullfile(TrackPath1,TrackFile1),'Tracklets','VideoName')
                % disp('----Load Tracklets done----')
            catch
                StatusStr='ERROR: file do not contains Tracklets var';
                app.StatusTextArea.Value = StatusStr;
                % disp('----ERROR: file do not contains Tracklets var ----')
                return
            end
            app.PathTextArea.Value=fullfile(TrackPath1,TrackFile1);
            
            if ~exist(fullfile(TrackPath1,VideoName),'file')
                StatusStr='ERROR: Video and Tracklets are not in the same folder';
                app.StatusTextArea.Value = StatusStr;
                % disp('----ERROR: Video and Tracklets are not in the same folder----')
                return
            end
%             CurrentPath=pwd;
%             cd(TrackPath1)
%             [VideoName,VideoPath] = uigetfile('*.avi','Load Video...');
%             cd(CurrentPath)
%             if strcmp(TrackPath1,VideoPath)
%                 % disp('----Load Video done----')
%             else
%                 StatusStr='ERROR: Video and Tracklets are not in the same folder';
%                 app.StatusTextArea.Value = StatusStr;
%                 % disp('----ERROR: Video and Tracklets are not in the same folder----')
%                 return
%             end
            StatusStr='Load Tracklets and Video done';
            app.StatusTextArea.Value = StatusStr;
            % updata TrackLetModiStruct
            app.TrackPath=TrackPath1;
            app.TrackFile=TrackFile1;
            app.TrackOrigi=Tracklets;
            app.TrackModi=Tracklets;
            app.VideoObj.VideoName=VideoName;
        end

        % Value changed function: GantPlotCheckBox
        function GantPlotCheckBoxValueChanged(app, event)
            value = app.GantPlotCheckBox.Value;
            if value
                app.RiskPlotCheckBox.Value=0;
                % actions
                TR_Dura=[app.TrackModi(:).StartEnd]';
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                for ci=1:length(app.TrackModi)
                    plot(app.UIAxes,TR_Dura(ci,:),[ci ci],'r-')
                end
                app.UIAxes.Visible = 'on';
                app.UIAxes.XLabel.String = 'Frame index';
                app.UIAxes.YLabel.String = 'Tracklets ID';
                app.UIAxes.XLim = [0 max(TR_Dura(:,2))];
                app.UIAxes.XAxis.Exponent=0;
                
                StatusStr='Gant Plot done';
                app.StatusTextArea.Value = StatusStr;
            end
        end

        % Value changed function: RiskPlotCheckBox
        function RiskPlotCheckBoxValueChanged(app, event)
            value = app.RiskPlotCheckBox.Value;
            if value
                app.GantPlotCheckBox.Value=0;
                % actions
                if length(app.TrackModi)~=app.ObjNumber
                    StatusStr='Error: Number of Tracklets is not equal to ObjNumber';
                    app.StatusTextArea.Value = StatusStr;
                    return
                end
                % Calculate distance threshold
                TR1=app.TrackModi(1).polybbox(:,:,1:20:2000);
                TRx=double(squeeze(TR1(3,1,:)-TR1(2,1,:)));
                TRy=double(squeeze(TR1(2,2,:)-TR1(1,2,:)));
                TR_Dist=sqrt(TRx.*TRx+TRy.*TRy);
                DistThre=prctile(TR_Dist,50)*0.3;
                % Calculate center of each trace
                TR_Dura=[app.TrackModi(:).StartEnd];
                RiskTrace=zeros(max(TR_Dura(2,:)),1,'logical');  % **
                
                CenXY=cell(length(app.TrackModi),2);  % center length
                for ci=1:length(app.TrackModi)
                    TR1=double(app.TrackModi(ci).polybbox);
                    TR1=squeeze(mean(TR1,1));
                    CenXY{ci,1}=TR1';
                    CenXY{ci,2}=size(TR1,2);
                end
                % Calculate distance
                Comp=nchoosek(1:length(app.TrackModi),2);
                for ci=1:size(Comp,1)
                    MinLength=min([CenXY{Comp(ci,1),2} CenXY{Comp(ci,2),2}]);
                    TR_Dist=CenXY{Comp(ci,1),1}(1:MinLength,:)-CenXY{Comp(ci,2),1}(1:MinLength,:);
                    TR_Dist=sqrt(sum(TR_Dist.*TR_Dist,2));
                    TR_DistFlag=TR_Dist<=DistThre;
                    RiskTrace(1:MinLength)=max(RiskTrace(1:MinLength),TR_DistFlag);
                end
                cla(app.UIAxes)
                hold(app.UIAxes,'on')
                plot(app.UIAxes,RiskTrace,'r-')
                app.UIAxes.Visible = 'on';
                app.UIAxes.XLabel.String = 'Frame index';
                app.UIAxes.YLabel.String = 'Risk Flag';
                app.UIAxes.YLim = [-0.5 1.5];
                app.UIAxes.XAxis.Exponent=0;
                
                StatusStr='Risk Plot done';
                app.StatusTextArea.Value = StatusStr;
            end
        end

        % Button pushed function: ChooseRangesButton
        function ChooseRangesButtonPushed(app, event)
            h = drawrectangle(app.UIAxes);
            TR1=h.Position;
            TR2=round([TR1(1) TR1(1)+TR1(3)]);
            TR_Dura=[app.TrackModi(:).StartEnd];
            TR2(1)=max([TR2(1) 1]);
            TR2(2)=min([TR2(2) max(TR_Dura(2,:))]);
            app.ChooseRange=TR2;
            % app.ChooseRange=[5200 5900];
            app.LeftEditField.Value = num2str(app.ChooseRange(1));
            app.RightEditField.Value = num2str(app.ChooseRange(2));
            % ChooseRangeTrack
            FrameInd=app.ChooseRange;
            TR_Track=app.TrackModi;
            TR_Track=TrackletsCut(TR_Track,FrameInd);
            app.ChooseRangeTrack = TR_Track;
            
            StatusStr='Choose ranges done';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: VideoPlotButton
        function VideoPlotButtonPushed(app, event)
            global ImRatio
            
            % ChooseRangeTrack
            FrameInd=app.ChooseRange;
            TR_Track=app.TrackModi;
            TR_Track=TrackletsCut(TR_Track,FrameInd);
            app.ChooseRangeTrack = TR_Track;
            
            % plot track video
            VideoName = app.VideoObj.VideoName;
            VideoObj1=[];
            VideoObj1.Obj = VideoReader(fullfile(app.TrackPath,VideoName));
            TrackletsPlotSub_VR(app.ChooseRangeTrack,VideoObj1,[],ImRatio,'Video');
            
            StatusStr='Video Plot done';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: BreakUpdateButton
        function BreakUpdateButtonPushed(app, event)
            DataBreak = table2array(app.UITable.Data);
            FrameStep=3;
            TR_Track = app.ChooseRangeTrack;
            Flag = 0;
            % break
            for ci=1:size(DataBreak,1)
                TrID = DataBreak(ci,1);
                if TrID>0
                    TR1 = sort(DataBreak(ci,2:end),'descend');
                    for cj=1:length(TR1)
                        if TR1(cj)>0    % target tracklets and break point
                            Flag=1;
                            TR_ind_0 = (TR1(cj)-1)*FrameStep+1; % frame step: 3
                            TR_ind = TR_ind_0-(TR_Track(TrID).StartEnd(1)-app.ChooseRange(1));
                            NewTrack=struct('frame',[],'StartEnd',[],'polybbox',[]);
                            NewTrack.frame = TR_Track(TrID).frame(TR_ind+FrameStep:end);
                            NewTrack.StartEnd = NewTrack.frame([1 end]);
                            NewTrack.polybbox = TR_Track(TrID).polybbox(:,:,TR_ind+FrameStep:end);
                            TR_Track(end+1) = NewTrack;
                            
                            TR_Track(TrID).frame(TR_ind-FrameStep:end)=[];
                            TR_Track(TrID).StartEnd = TR_Track(TrID).frame([1 end]);
                            TR_Track(TrID).polybbox(:,:,TR_ind-FrameStep:end)=[];
                        end
                    end
                end
            end
            app.ChooseRangeTrack = TR_Track;
            app.UITable.Data = [];
            % Update app.TrackModi, Methods (GantPlot or RiskPlot), Video
            if Flag
                % UpdateTracklets(app)
                TR_Track = app.ChooseRangeTrack;
                FrameInd=app.ChooseRange;
                TR_Track_Pre= TrackletsCut(app.TrackModi,[1 FrameInd(1)]);
                TR_Dura=[app.TrackModi(:).StartEnd];
                TR_Track_Post=TrackletsCut(app.TrackModi,[FrameInd(end) max(TR_Dura(2,:))]);
                app.TrackModi=TrackletsMergeOperationCross_V1(TR_Track_Pre,TR_Track,TR_Track_Post);
                
                % VideoPlotButtonPushed(app, event)
                if app.GantPlotCheckBox.Value
                    GantPlotCheckBoxValueChanged(app, event)
                end
                if app.RiskPlotCheckBox.Value
                    RiskPlotCheckBoxValueChanged(app, event)
                end
            end
            StatusStr='Update Tracklets Break done';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: AddTrackBreakButton
        function AddTrackBreakButtonPushed(app, event)
            TR_Data = zeros(5,5);
            % TR_Data(1,:) = [4,24,201,0,0];
            a=table(TR_Data(:,1),TR_Data(:,2),TR_Data(:,3),TR_Data(:,4),TR_Data(:,5));
            a.Properties.VariableNames={'Tracklet ID'; 'Frame ID-1'; 'Frame ID-2'; 'Frame ID-3'; 'Frame ID-4'};
            app.UITable.Data = a;
            
            StatusStr='';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            TrackLetsModi=[];
            % TrackLetsModi.TrackPath = app.TrackPath;
            % TrackLetsModi.TrackFile = app.TrackFile;
            TrackLetsModi.ObjNumber = app.ObjNumber;
            TrackLetsModi.TrackOrigi = app.TrackOrigi;
            TrackLetsModi.TrackModi = app.TrackModi;
            TrackLetsModi.VideoObj = app.VideoObj;
            
            TR_name_0=app.TrackFile(1:end-4);
            if length(TR_name_0)>16
                if strcmp(TR_name_0(end-6),'_') && strcmp(TR_name_0(end-15),'_')
                    TR_name_0=TR_name_0(1:end-16);
                end
            end
            TR_name_0=strrep(TR_name_0,'_Modi','');
            
            TR_time=char(datetime);
            TR_time=strrep(TR_time,'-','');
            TR_time=strrep(TR_time,':','');
            TR_time=strrep(TR_time,' ','_');
            
            TR_File=[TR_name_0 '_Modi_' TR_time '.mat'];
            % TrackLetsModi.TrackFile = TR_File;
            save(fullfile(app.TrackPath,TR_File),'TrackLetsModi')
            
            StatusStr='Save Results (TrackLetsModi) done';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [TrackFile1,TrackPath1] = uigetfile('','Load TrackModi...');
            try
                load(fullfile(TrackPath1,TrackFile1),'TrackLetsModi')
            catch
            end
            if exist('TrackLetsModi','var')
                % disp('----Load TrackModi done----')
            else
                StatusStr='ERROR: No TrackLetsModi var';
                app.StatusTextArea.Value = StatusStr;
                % disp('----ERROR: no TrackLetsModi var----')
                return
            end
            if exist(fullfile(TrackPath1,TrackLetsModi.VideoObj.VideoName),'file')
                % disp('----Detect target Video done----')
            else
                StatusStr='ERROR: No video file in target folder';
                app.StatusTextArea.Value = StatusStr;
                % disp('----ERROR: No video file in target folder')
            end
            app.PathTextArea.Value=fullfile(TrackPath1,TrackFile1);
            % updata TrackLetModiStruct
            app.TrackPath=TrackPath1;
            app.TrackFile=TrackFile1;
            
            app.ObjNumber = TrackLetsModi.ObjNumber;
            app.TrackOrigi=TrackLetsModi.TrackOrigi;
            app.TrackModi=TrackLetsModi.TrackModi;
            app.VideoObj=TrackLetsModi.VideoObj;
            
            app.ObjNumEditField.Value = num2str(app.ObjNumber);
            app.GantPlotCheckBox.Value = 1;
            GantPlotCheckBoxValueChanged(app, event)
            
            StatusStr='Load Tracklets and Video (TrackLetsModi) done';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: AddTrackConnectButton
        function AddTrackConnectButtonPushed(app, event)
            TR_Data = zeros(9,2);
            % TR_Data(1:3,:) = [1 5; 4 6; 5 7];
            a=table(TR_Data(:,1),TR_Data(:,2));
            a.Properties.VariableNames={'Tracklet ID-1'; 'Tracklet ID-2'};
            app.UITable2.Data = a;
            
            StatusStr='';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: ConnectUpdateButton
        function ConnectUpdateButtonPushed(app, event)
            Flag=0;
            DataConnect = table2array(app.UITable2.Data);
            TR_Flag = zeros(size(DataConnect,1),1);
            for ci=1:size(DataConnect,1)
                if min(DataConnect(ci,:))>0
                    Flag=1;
                    TR_Flag(ci)=1;
                end
            end
            DataConnect=DataConnect(TR_Flag==1,:);
            TR_Track = app.ChooseRangeTrack;
            TR_Track = TrackletsMergeOperation_V1(TR_Track,DataConnect);
            app.ChooseRangeTrack = TR_Track;
            app.UITable2.Data=[];
            % Update app.TrackModi, Methods (GantPlot or RiskPlot), Video
            if Flag
                FrameInd=app.ChooseRange;
                TR_Track_Pre= TrackletsCut(app.TrackModi,[1 FrameInd(1)]);
                TR_Dura=[app.TrackModi(:).StartEnd];
                TR_Track_Post=TrackletsCut(app.TrackModi,[FrameInd(end) max(TR_Dura(2,:))]);
                app.TrackModi=TrackletsMergeOperationCross_V1(TR_Track_Pre,TR_Track,TR_Track_Post);
                % delete cover tracklets
                ThreCover=0.2;  % cover threshold
                ThreSeg=[10 10 8];   % gap overlap length | 逐步减弱条件 + + -
                ObjN=app.ObjNumber;
                app.TrackModi=TrackletsMergeCover_V1(app.TrackModi,ObjN,ThreSeg,ThreCover); % delete | not merging
                % VideoPlotButtonPushed(app, event)
                if app.GantPlotCheckBox.Value
                    GantPlotCheckBoxValueChanged(app, event)
                end
                if app.RiskPlotCheckBox.Value
                    RiskPlotCheckBoxValueChanged(app, event)
                end
            end
            StatusStr='Update Tracklets Connection done';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: WriteVideoButton
        function WriteVideoButtonPushed(app, event)
            % plot track video
            global ImRatio
            VideoName = app.VideoObj.VideoName;
            VideoObj1=[];
            VideoObj1.Obj = VideoReader(fullfile(app.TrackPath,VideoName));
            TrackletsPlotSub_VR(app.TrackModi,VideoObj1,[],ImRatio,'VideoTracking');
            
            StatusStr='Write Video done';
            app.StatusTextArea.Value = StatusStr;
        end

        % Button pushed function: ObjNumButton
        function ObjNumButtonPushed(app, event)
            value = app.ObjNumEditField.Value;
            app.ObjNumber = double(string(value));
        end

        % Button pushed function: DeleteFullycontainedButton
        function DeleteFullycontainedButtonPushed(app, event)
            TR_Track = app.ChooseRangeTrack;
            FrameInd=app.ChooseRange;
            DelFlag=zeros(length(TR_Track),1);
            for ci=1:length(TR_Track)
                if TR_Track(ci).StartEnd(1)>FrameInd(1) && TR_Track(ci).StartEnd(2)<FrameInd(2)
                    DelFlag(ci)=1;
                end
            end
            TR_Track(DelFlag==1)=[];
            app.ChooseRangeTrack = TR_Track;
            
            TR_Track_Pre= TrackletsCut(app.TrackModi,[1 FrameInd(1)]);
            TR_Dura=[app.TrackModi(:).StartEnd];
            TR_Track_Post=TrackletsCut(app.TrackModi,[FrameInd(end) max(TR_Dura(2,:))]);
            app.TrackModi=TrackletsMergeOperationCross_V1(TR_Track_Pre,TR_Track,TR_Track_Post);
            
            % VideoPlotButtonPushed(app, event)
            if app.GantPlotCheckBox.Value
                GantPlotCheckBoxValueChanged(app, event)
            end
            if app.RiskPlotCheckBox.Value
                RiskPlotCheckBoxValueChanged(app, event)
            end
        end

        % Button pushed function: AddManualTrackingButton
        function AddManualTrackingButtonPushed(app, event)
            % plot track video
            VideoName = app.VideoObj.VideoName;
            VideoObj1=VideoReader(fullfile(app.TrackPath,VideoName));
            % VideoObj1.Obj = VideoReader(fullfile(app.TrackPath,VideoName));
            % TrackletsPlotSub_V2(app.TrackModi,VideoObj1,[],'VideoTracking');
            % get trace
            TR_Track=app.ChooseRangeTrack;
            TR_Frame=app.ChooseRange;
            
            figure(11)
            LocsXY=zeros(TR_Frame(2)-TR_Frame(2)+1,2);
            Color_ind=hsv(length(TR_Track));
            TR_Dura=[TR_Track(:).StartEnd]';
            % ImRatio=0.4;
            global ImRatio
            FrameStep=2;
            TR_ind=unique([TR_Frame(1):FrameStep:TR_Frame(2) TR_Frame(2)]);
            for ci=TR_ind
                figure(11); hold off
                VideoObj1.CurrentTime=(double(ci)-1)/VideoObj1.Framerate; % video 索引是从0开始的
                frame_0 = readFrame(VideoObj1);     % 读取当前帧
                frame_0=imresize(frame_0,ImRatio);
                cen_T=[];
                for cj=1:length(TR_Track)  % tracklets
                    if TR_Dura(cj,1)<=ci && TR_Dura(cj,2)>=ci
                        polybbox=TR_Track(cj).polybbox(:,:,ci-TR_Dura(cj,1)+1);
                        polybbox=double(polybbox);
                        cen=mean(polybbox,1);
                        cen=permute(cen,[3 2 1]);
                        cen=cen*ImRatio;
                        cen_T=[cen_T; cen 8 cj];
                    end
                end
                frame_1=insertShape(frame_0,'FilledCircle',cen_T(:,1:3),'Color',Color_ind(cen_T(:,4),:)*255,'Opacity',0.6);
                frame_2=insertText(frame_1,[cen_T(:,1) cen_T(:,2)],cen_T(:,4),'TextColor','w',...
                    'AnchorPoint','Center','BoxOpacity',0,'FontSize',15);
                % FrameProcess=[num2str(ci) ' | ' num2str(TR_frame(end))];
                FrameProcess=(ci-TR_ind(1)+1)/(TR_ind(end)-TR_ind(1)+1);
                frame_2=insertText(frame_2,[15 10],FrameProcess,'TextColor','w',...
                    'AnchorPoint','LeftTop','BoxOpacity',0,'FontSize',18);
            
                imshow(frame_2)
                [x,y]=ginput(1);
                LocsXY(ci-TR_Frame(1)+1,:)=[x y];
            end
            
            TR1=find(LocsXY(:,1)==0);
            for ci=TR1'
                LocsXY(ci,:)=mean(LocsXY([ci-1 ci+1],:),1);
            end
            LocsXY=LocsXY/ImRatio;
            % assign the trace ID pre
            DistPre=zeros(length(TR_Track),1);
            cen_T=[];
            for ci=1:length(TR_Track)
                if TR_Track(ci).StartEnd(1)>TR_Frame(1)
                    DistPre(ci,1)=inf;
                    cen_T=[cen_T; nan nan];
                else
                    polybbox=TR_Track(ci).polybbox(:,:,1);
                    polybbox=double(polybbox);
                    cen=mean(polybbox,1);
                    cen=permute(cen,[3 2 1]);
                    cen_T=[cen_T; cen];
                    TR2=cen-LocsXY(1,:);
                    DistPre(ci,1)=sqrt(sum(TR2.*TR2));
                end
            end
            [~,IDPre]=min(DistPre);
            LocsXY(1,:)=cen_T(IDPre,:);
            % assign the trace ID post
            DistPost=zeros(length(TR_Track),1);
            cen_T=[];
            for ci=1:length(TR_Track)
                if TR_Track(ci).StartEnd(2)<TR_Frame(2)
                    DistPost(ci,1)=inf;
                    cen_T=[cen_T; nan nan];
                else
                    polybbox=TR_Track(ci).polybbox(:,:,end);
                    polybbox=double(polybbox);
                    cen=mean(polybbox,1);
                    cen=permute(cen,[3 2 1]);
                    cen_T=[cen_T; cen];
                    TR2=cen-LocsXY(end,:);
                    DistPost(ci,1)=sqrt(sum(TR2.*TR2));
                end
            end
            [~,IDPost]=min(DistPost);
            LocsXY(end,:)=cen_T(IDPost,:);
            % generate bbox
            Len=5;
            bbox_0 = [-1 -1 1 1; 1 -1 -1 1]';
            bbox_0=bbox_0*Len;
            LocsPolybbox=repmat(bbox_0,1,1,size(LocsXY,1));
            LocsXY_1=permute(LocsXY,[3 2 1]);
            LocsXY_1=repmat(LocsXY_1,4,1,1);
            LocsPolybbox=LocsPolybbox+LocsXY_1;
            % % LocsPolybbox=uint32(LocsPolybbox);
            % update tracklets
            allframe=(TR_Frame(1):TR_Frame(2))';
            NewTrack=struct('frame',uint32(allframe),'StartEnd',allframe([1 end]),'polybbox',LocsPolybbox);
            TR_Track([IDPre IDPost])=[];
            TR_Track(end+1)=NewTrack;
            FrameInd=app.ChooseRange;
            TR_Track_Pre= TrackletsCut(app.TrackModi,[1 FrameInd(1)]);
            TR_Dura=[app.TrackModi(:).StartEnd];
            TR_Track_Post=TrackletsCut(app.TrackModi,[FrameInd(end) max(TR_Dura(2,:))]);
            
            app.TrackModi=TrackletsMergeOperationCross_V1(TR_Track_Pre,TR_Track,TR_Track_Post);
            % VideoPlotButtonPushed(app, event)
            if app.GantPlotCheckBox.Value
                GantPlotCheckBoxValueChanged(app, event)
            end
            if app.RiskPlotCheckBox.Value
                RiskPlotCheckBoxValueChanged(app, event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadTrackletsButton = uibutton(app.UIFigure, 'push');
            app.LoadTrackletsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrackletsButtonPushed, true);
            app.LoadTrackletsButton.Text = 'Load Tracklets';
            app.PathTextAreaLabel = uilabel(app.UIFigure);
            app.PathTextAreaLabel.Text = 'Path';
            app.PathTextArea = uitextarea(app.UIFigure);
            app.PathTextArea.Editable = 'off';
            app.MethodsButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodsButtonGroup.Title = 'Methods';
            app.GantPlotCheckBox = uicheckbox(app.MethodsButtonGroup);
            app.GantPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @GantPlotCheckBoxValueChanged, true);
            app.GantPlotCheckBox.Text = 'Gant Plot';
            app.RiskPlotCheckBox = uicheckbox(app.MethodsButtonGroup);
            app.RiskPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @RiskPlotCheckBoxValueChanged, true);
            app.RiskPlotCheckBox.Text = 'Risk Plot';
            app.FrameRangePanel = uipanel(app.UIFigure);
            app.FrameRangePanel.Title = 'Frame Range';
            app.LeftLabel = uilabel(app.FrameRangePanel);
            app.LeftLabel.Text = 'Left  ';
            app.LeftEditField = uieditfield(app.FrameRangePanel, 'text');
            app.LeftEditField.Editable = 'off';
            app.RightEditFieldLabel = uilabel(app.FrameRangePanel);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightEditField = uieditfield(app.FrameRangePanel, 'text');
            app.RightEditField.Editable = 'off';
            app.ChooseRangesButton = uibutton(app.FrameRangePanel, 'push');
            app.ChooseRangesButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseRangesButtonPushed, true);
            app.ChooseRangesButton.Text = {'Choose'; 'Ranges'};
            app.VideoPlotButton = uibutton(app.FrameRangePanel, 'push');
            app.VideoPlotButton.ButtonPushedFcn = createCallbackFcn(app, @VideoPlotButtonPushed, true);
            app.VideoPlotButton.Text = {'Video'; ' Plot'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.LoadButton = uibutton(app.ButtonGroup, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.WriteVideoButton = uibutton(app.ButtonGroup, 'push');
            app.WriteVideoButton.ButtonPushedFcn = createCallbackFcn(app, @WriteVideoButtonPushed, true);
            app.WriteVideoButton.Text = 'Write Video';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BreakTrackletTab = uitab(app.TabGroup);
            app.BreakTrackletTab.Title = 'Break Tracklet';
            app.UITable = uitable(app.BreakTrackletTab);
            app.UITable.ColumnName = {'Tracklet ID'; 'Frame ID-1'; 'Frame ID-2'; 'Frame ID-3'; 'Frame ID-4'};
            app.UITable.ColumnEditable = true;
            app.BreakUpdateButton = uibutton(app.BreakTrackletTab, 'push');
            app.BreakUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @BreakUpdateButtonPushed, true);
            app.BreakUpdateButton.Text = 'Break & Update';
            app.AddTrackBreakButton = uibutton(app.BreakTrackletTab, 'push');
            app.AddTrackBreakButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrackBreakButtonPushed, true);
            app.AddTrackBreakButton.Text = 'Add Track-Break';
            app.ConnectTrackletsTab = uitab(app.TabGroup);
            app.ConnectTrackletsTab.Title = 'Connect Tracklets';
            app.UITable2 = uitable(app.ConnectTrackletsTab);
            app.UITable2.ColumnName = {'Tracklet ID-1'; 'Tracklet ID-2'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.AddTrackConnectButton = uibutton(app.ConnectTrackletsTab, 'push');
            app.AddTrackConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrackConnectButtonPushed, true);
            app.AddTrackConnectButton.Text = 'Add Track-Connect';
            app.ConnectUpdateButton = uibutton(app.ConnectTrackletsTab, 'push');
            app.ConnectUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectUpdateButtonPushed, true);
            app.ConnectUpdateButton.Text = 'Connect & Update';
            app.ManualTrackletsTab = uitab(app.TabGroup);
            app.ManualTrackletsTab.Title = 'Manual Tracklets';
            app.DeleteFullycontainedButton = uibutton(app.ManualTrackletsTab, 'push');
            app.DeleteFullycontainedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFullycontainedButtonPushed, true);
            app.DeleteFullycontainedButton.Text = 'Delete Fully contained';
            app.AddManualTrackingButton = uibutton(app.ManualTrackletsTab, 'push');
            app.AddManualTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @AddManualTrackingButtonPushed, true);
            app.AddManualTrackingButton.Text = 'Add Manual Tracking';
            app.ObjNumEditField = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.ObjNumButton = uibutton(app.UIFigure, 'push');
            app.ObjNumButton.ButtonPushedFcn = createCallbackFcn(app, @ObjNumButtonPushed, true);
            app.ObjNumButton.Text = 'ObjNum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOT_CC_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end